classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OverallGenerationsfitnessLabel  matlab.ui.control.Label
        MinimumEditField                matlab.ui.control.EditField
        MinimumEditFieldLabel           matlab.ui.control.Label
        MaximumEditField                matlab.ui.control.EditField
        MaximumLabel                    matlab.ui.control.Label
        AverageEditField                matlab.ui.control.EditField
        AverageLabel                    matlab.ui.control.Label
        DecodedOutputTextArea           matlab.ui.control.TextArea
        DecodedOutputLabel              matlab.ui.control.Label
        ItemValueMaximizationSystemLabel  matlab.ui.control.Label
        Pleaseratetheitemsvaluein1to10Label  matlab.ui.control.Label
        Note1meanstheitemischosen0meanstheitemisnotchosenLabel  matlab.ui.control.Label
        EditField14                     matlab.ui.control.EditField
        BestSolutionLabel               matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        EditField13                     matlab.ui.control.EditField
        EditField12                     matlab.ui.control.EditField
        EditField11                     matlab.ui.control.EditField
        EditField10                     matlab.ui.control.EditField
        EditField9                      matlab.ui.control.EditField
        MutationrateLabel               matlab.ui.control.Label
        CrossoverrateLabel              matlab.ui.control.Label
        NumberofgenerationsLabel        matlab.ui.control.Label
        PopulationsizeLabel             matlab.ui.control.Label
        CapacitykgLabel                 matlab.ui.control.Label
        Hairdryer1kgLabel               matlab.ui.control.Label
        Toiletries5kgLabel              matlab.ui.control.Label
        Drinks3kgLabel                  matlab.ui.control.Label
        Clothes4kgLabel                 matlab.ui.control.Label
        Shoes2kgLabel                   matlab.ui.control.Label
        Firstaidbox2kgLabel             matlab.ui.control.Label
        Laptop3kgLabel                  matlab.ui.control.Label
        Phone1kgLabel                   matlab.ui.control.Label
        EditField8                      matlab.ui.control.EditField
        EditField7                      matlab.ui.control.EditField
        EditField6                      matlab.ui.control.EditField
        EditField5                      matlab.ui.control.EditField
        EditField4                      matlab.ui.control.EditField
        EditField3                      matlab.ui.control.EditField
        EditField2                      matlab.ui.control.EditField
        Image8                          matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        EditField                       matlab.ui.control.EditField
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function pop = init_pop(app, pop_size, n_items)
                pop = round(rand(pop_size, n_items));
        end
        
        function fitness = eval_fitness(app, pop, items, weights, values, capacity)
            n_chromosomes = size(pop, 1);
            fitness = zeros(n_chromosomes, 1);
            for i = 1:n_chromosomes
                chromosome = pop(i, :);
                total_weight = sum(chromosome .* weights);
                total_value = sum(chromosome .* values);
                if total_weight > capacity
                    fitness(i) = 0;
                else
                    fitness(i) = total_value;
                end
            end
        end
        
        % Roulette Wheel selection
%         function parents = selection(app, pop, fitness)
%             n_chromosomes = size(pop, 1);  
%             cumulative_fitness = cumsum(fitness);
%             parents = zeros(n_chromosomes, size(pop, 2));   % create parents chromosomes storage
%             for i = 1:n_chromosomes
%                 r = rand() * cumulative_fitness(end);   % rand() is 0 to 1 probability
%                 parent_idx = find(cumulative_fitness >= r, 1); % in the 50 rows of chromosomes in the 
%                                                                 % cumulative fitness from up to bottom get the 
%                                                                 % first value that greater than r then this 
%                                                                 % will be the index for parents to choose 
%                                                                 % chromosomes belongs in the pop.
%                 parents(i, :) = pop(parent_idx, :);     % assign index of value in the pop to the parents row
%             end
%         end

          % Tournament selection
          function parents = selection(app, pop, fitness)
              n_chromosomes = size(pop, 1);
              K = 4; % tournament size
              parents = zeros(n_chromosomes, size(pop, 2)); % create parents chromosomes storage
            
              for i = 1:n_chromosomes
                  tournament = randi(n_chromosomes, [1, K]); % choose K random individuals from the population
                  [~, best_idx] = max(fitness(tournament)); % find the best fitness among the K individuals
                  parent_idx = tournament(best_idx); % select the individual with the best fitness
                  parents(i, :) = pop(parent_idx, :); % assign index of value in the pop to the parents row
              end
          end
        
%         function offspring = crossover(app, parents, crossover_rate)
%             n_chromosomes = size(parents, 1);
%             offspring = zeros(n_chromosomes, size(parents, 2));
%             for i = 1:2:n_chromosomes
%                 parent1 = parents(i, :);
%                 parent2 = parents(i+1, :);
%                 r = rand();
%                 if r < crossover_rate
%                     crossover_point = randi(size(parent1, 2));
%                     offspring(i, 1:crossover_point) = parent1(1:crossover_point);
%                     offspring(i, (crossover_point+1):end) = parent2((crossover_point+1):end);
%                     offspring(i+1, 1:crossover_point) = parent2(1:crossover_point);
%                     offspring(i+1, (crossover_point+1):end) = parent1((crossover_point+1):end);
%                 else
%                     offspring(i, :) = parent1;
%                     offspring(i+1, :) = parent2;
%                 end
%             end
%         end

        % Two point
        function offspring = crossover(app, parents, crossover_rate)
            n_chromosomes = size(parents, 1);
            offspring = zeros(n_chromosomes, size(parents, 2));
            for i = 1:2:n_chromosomes
                parent1 = parents(i, :);
                parent2 = parents(i+1, :);
                r = rand();
                if r < crossover_rate
                    crossover_point1 = randi(size(parent1, 2) - 1);
                    crossover_point2 = randi(crossover_point1+1, size(parent1, 2));
                    offspring(i, 1:crossover_point1) = parent1(1:crossover_point1);
                    offspring(i, (crossover_point1+1):crossover_point2) = parent2((crossover_point1+1):crossover_point2);
                    offspring(i, (crossover_point2+1):end) = parent1((crossover_point2+1):end);
                    offspring(i+1, 1:crossover_point1) = parent2(1:crossover_point1);
                    offspring(i+1, (crossover_point1+1):crossover_point2) = parent1((crossover_point1+1):crossover_point2);
                    offspring(i+1, (crossover_point2+1):end) = parent2((crossover_point2+1):end);
                else
                    offspring(i, :) = parent1;
                    offspring(i+1, :) = parent2;
                end
            end
        end
        
        function offspring = mutation(app, offspring, mutation_rate)
            n_chromosomes = size(offspring, 1);
            for i = 1:n_chromosomes
                chromosome = offspring(i, :);
                for j = 1:numel(chromosome)
                    r = rand();
                    if r < mutation_rate
                        chromosome(j) = ~chromosome(j);
                    end
                end
                offspring(i, :) = chromosome;
            end
        end
        
        function pop = update_pop(app, pop, offspring, fitness)
            n_chromosomes = size(pop, 1);
            new_pop = [pop; offspring];
            new_fitness = [fitness; fitness];
            [~, idx] = sort(new_fitness, 'descend');
            pop = new_pop(idx(1:n_chromosomes), :);
            fitness = new_fitness(idx(1:n_chromosomes));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            % Define problem
            items = {'Phone', 'Laptop', 'First aid box', 'Shoes', 'Clothes', 'Drinks', 'Toiletries', 'Hair dryer'};
            weights = [1, 3, 2, 2, 4, 3, 5, 1];
            values = [str2double(app.EditField.Value), str2double(app.EditField2.Value), str2double(app.EditField3.Value), str2double(app.EditField4.Value),... 
                str2double(app.EditField5.Value), str2double(app.EditField6.Value), str2double(app.EditField7.Value), str2double(app.EditField8.Value)];
            
            % Parameters
            capacity = str2double(app.EditField9.Value);
            pop_size = str2double(app.EditField10.Value);
            n_generations = str2double(app.EditField11.Value);
            crossover_rate = str2double(app.EditField12.Value);
            mutation_rate = str2double(app.EditField13.Value);
            mean_fitness = zeros(n_generations, 1);
            max_fitness = zeros(n_generations, 1);
            min_fitness = zeros(n_generations, 1);
            
            % Initialize population
            pop = init_pop(app, pop_size, numel(items));
            
            % Evaluate fitness
            fitness = eval_fitness(app, pop, items, weights, values, capacity);
            
            % Main loop
            for i = 1:n_generations
                % Selection
                parents = selection(app, pop, fitness);
                
                % Crossover
                offspring = crossover(app, parents, crossover_rate);
                
                % Mutation
                offspring = mutation(app, offspring, mutation_rate);
                
                % Evaluate fitness
                fitness = eval_fitness(app, offspring, items, weights, values, capacity);
                mean_fitness(i) = mean(fitness);
                max_fitness(i) = max(fitness(find(fitness)));
                min_fitness(i) = min(fitness(find(fitness)));
            
                % Update population
                pop = update_pop(app, pop, offspring, fitness);
            end
            
            % Plot the graph
            %figure;
            plot(app.UIAxes, mean_fitness);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, max_fitness);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, min_fitness);
            title(app.UIAxes, 'Fitness VS Generations')
            xlabel(app.UIAxes, 'Generations');
            ylabel(app.UIAxes, 'Fitness Value');
            legend(app.UIAxes, 'Mean','Max','Min');
            hold(app.UIAxes,'off')
            
            % Overall generations fitness
            app.AverageEditField.Value = num2str(mean(mean_fitness));
            app.MaximumEditField.Value = num2str(mean(max_fitness));
            app.MinimumEditField.Value = num2str(mean(min_fitness));

            % Get the best solution
            [~, idx] = max(fitness(find(fitness)));
            best_sol = pop(idx, :);
            app.EditField14.Value = num2str(best_sol);
            
            count=0;
            for i = 1:length(best_sol)
                if best_sol(:,i) == 1
                    count = count + 1;
                end
            end
            
            b={};
            j=1;
               
            for i = 1:length(items)
                if best_sol(:,i) == 1
                        b{j,1} = char(items(i));
                        j=j+1;
                end
            end
            app.DecodedOutputTextArea.Value = b;
            

        end

        % Value changed function: EditField9
        function EditField9ValueChanged(app, event)

        end

        % Value changed function: EditField10
        function EditField10ValueChanged(app, event)

        end

        % Value changing function: EditField11
        function EditField11ValueChanging(app, event)

        end

        % Value changed function: EditField12
        function EditField12ValueChanged(app, event)

            
        end

        % Value changed function: EditField13
        function EditField13ValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'phone.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'laptop.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'first aid box.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'shoes.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'images', 'clothes.jpg');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '10';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'images', 'contener06.jpg');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'images', 'Toiletries.jpg');
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'images', 'hair dryer.jpg');
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.Value = '3';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField3.Value = '4';
            app.EditField4 = uieditfield(app.UIFigure, 'text');
            app.EditField4.Value = '8';
            app.EditField5 = uieditfield(app.UIFigure, 'text');
            app.EditField5.Value = '6';
            app.EditField6 = uieditfield(app.UIFigure, 'text');
            app.EditField6.Value = '2';
            app.EditField7 = uieditfield(app.UIFigure, 'text');
            app.EditField7.Value = '5';
            app.EditField8 = uieditfield(app.UIFigure, 'text');
            app.EditField8.Value = '1';
            app.Phone1kgLabel = uilabel(app.UIFigure);
            app.Phone1kgLabel.Text = '1. Phone (1 kg)';
            app.Laptop3kgLabel = uilabel(app.UIFigure);
            app.Laptop3kgLabel.Text = '2. Laptop (3kg)';
            app.Firstaidbox2kgLabel = uilabel(app.UIFigure);
            app.Firstaidbox2kgLabel.Text = '3. First aid box (2 kg)';
            app.Shoes2kgLabel = uilabel(app.UIFigure);
            app.Shoes2kgLabel.Text = '4. Shoes (2 kg)';
            app.Clothes4kgLabel = uilabel(app.UIFigure);
            app.Clothes4kgLabel.Text = '5. Clothes (4 kg)';
            app.Drinks3kgLabel = uilabel(app.UIFigure);
            app.Drinks3kgLabel.Text = '6. Drinks (3 kg)';
            app.Toiletries5kgLabel = uilabel(app.UIFigure);
            app.Toiletries5kgLabel.Text = '7. Toiletries (5 kg)';
            app.Hairdryer1kgLabel = uilabel(app.UIFigure);
            app.Hairdryer1kgLabel.Text = '8. Hair dryer (1 kg)';
            app.CapacitykgLabel = uilabel(app.UIFigure);
            app.CapacitykgLabel.Text = 'Capacity (kg):';
            app.PopulationsizeLabel = uilabel(app.UIFigure);
            app.PopulationsizeLabel.Text = 'Population size:';
            app.NumberofgenerationsLabel = uilabel(app.UIFigure);
            app.NumberofgenerationsLabel.Text = 'Number of generations:';
            app.CrossoverrateLabel = uilabel(app.UIFigure);
            app.CrossoverrateLabel.Text = 'Crossover rate:';
            app.MutationrateLabel = uilabel(app.UIFigure);
            app.MutationrateLabel.Text = 'Mutation rate:';
            app.EditField9 = uieditfield(app.UIFigure, 'text');
            app.EditField9.ValueChangedFcn = createCallbackFcn(app, @EditField9ValueChanged, true);
            app.EditField9.Value = '10';
            app.EditField10 = uieditfield(app.UIFigure, 'text');
            app.EditField10.ValueChangedFcn = createCallbackFcn(app, @EditField10ValueChanged, true);
            app.EditField10.Value = '100';
            app.EditField11 = uieditfield(app.UIFigure, 'text');
            app.EditField11.ValueChangingFcn = createCallbackFcn(app, @EditField11ValueChanging, true);
            app.EditField11.Value = '100';
            app.EditField12 = uieditfield(app.UIFigure, 'text');
            app.EditField12.ValueChangedFcn = createCallbackFcn(app, @EditField12ValueChanged, true);
            app.EditField12.Value = '0.8';
            app.EditField13 = uieditfield(app.UIFigure, 'text');
            app.EditField13.ValueChangedFcn = createCallbackFcn(app, @EditField13ValueChanged, true);
            app.EditField13.Value = '0.1';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.BestSolutionLabel = uilabel(app.UIFigure);
            app.BestSolutionLabel.Text = 'Best Solution:';
            app.EditField14 = uieditfield(app.UIFigure, 'text');
            app.EditField14.Editable = 'off';
            app.Note1meanstheitemischosen0meanstheitemisnotchosenLabel = uilabel(app.UIFigure);
            app.Note1meanstheitemischosen0meanstheitemisnotchosenLabel.Text = 'Note: 1 means the item is chosen, 0 means the item is not chosen.';
            app.Pleaseratetheitemsvaluein1to10Label = uilabel(app.UIFigure);
            app.Pleaseratetheitemsvaluein1to10Label.Text = 'Please rate the items value in 1 to 10.';
            app.ItemValueMaximizationSystemLabel = uilabel(app.UIFigure);
            app.ItemValueMaximizationSystemLabel.Text = 'Item Value Maximization System';
            app.DecodedOutputLabel = uilabel(app.UIFigure);
            app.DecodedOutputLabel.Text = 'Decoded Output:';
            app.DecodedOutputTextArea = uitextarea(app.UIFigure);
            app.DecodedOutputTextArea.Editable = 'off';
            app.AverageLabel = uilabel(app.UIFigure);
            app.AverageLabel.Text = 'Average :';
            app.AverageEditField = uieditfield(app.UIFigure, 'text');
            app.AverageEditField.Editable = 'off';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Text = 'Maximum :';
            app.MaximumEditField = uieditfield(app.UIFigure, 'text');
            app.MaximumEditField.Editable = 'off';
            app.MinimumEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumEditFieldLabel.Text = 'Minimum: ';
            app.MinimumEditField = uieditfield(app.UIFigure, 'text');
            app.MinimumEditField.Editable = 'off';
            app.OverallGenerationsfitnessLabel = uilabel(app.UIFigure);
            app.OverallGenerationsfitnessLabel.Text = 'Overall Generations fitness:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end